classdef statistical < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        HTML                  matlab.ui.control.HTML
        PerformTest           matlab.ui.control.Button
        FeaturesLabel         matlab.ui.control.Label
        variables             matlab.ui.control.ListBox
        GroupLabel            matlab.ui.control.Label
        responses             matlab.ui.control.ListBox
        TestsButtonGroup      matlab.ui.container.ButtonGroup
        OneWayANOVAButton     matlab.ui.control.RadioButton
        TwoWayANOVAButton     matlab.ui.control.RadioButton
        NWayANOVAButton       matlab.ui.control.RadioButton
        MannWhitneyUButton    matlab.ui.control.RadioButton
        KruskalWallisButton   matlab.ui.control.RadioButton
        ttestButton           matlab.ui.control.RadioButton
        RanksumButton         matlab.ui.control.RadioButton
        PairedttestButton     matlab.ui.control.RadioButton
        TwosamplettestButton  matlab.ui.control.RadioButton
        AssignFeaturesButton  matlab.ui.control.Button
        GIPHY                 matlab.ui.control.HTML
    end

    
    properties (Access = private)
        data; % Description
        respons;
        predictors;
        selected_data;
        selected_group;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.data=mainapp;
            app.variables.Items=app.data.Properties.VariableNames;
            app.responses.Items=app.data.Properties.VariableNames;
            app.GIPHY.HTMLSource='stat.html';
            app.HTML.HTMLSource='stat2.html';

        end

        % Button pushed function: PerformTest
        function PerformTestPushed(app, event)


            %{
            
            statistical(data);
            
            switch selection
                
                case 'ANOVA'
                    un=unique(group)
                    un=un(~isnan(un))
                    if length(un)>2 & size(data,2)>1
                        
                    elseif length(un)==2 & size(data,2)>1
                        
                        
                    end
            
                case 'ttest'
                    un=unique(group)
                    un=un(~isnan(un))
                    if length(un)==1
                      [h,p,ci,stats]=ttest(data)
                     
                    elseif length(un)==2
                      [h,p,ci,stats]=ttest(data)  
                    
                    elseif length(un)>2
                        uiconfirm(app.GliomaIDHMutationDiagnosisToolUIFigure,'No appropriate for t-test. There are 3 groups. Try ANOVA!','Statistical Test','Options'...
                ,{'OK'},'DefaultOption',1)
                    else
                        uiconfirm(app.GliomaIDHMutationDiagnosisToolUIFigure,'There is no grouping information try again!','Statistical Test','Options'...
                ,{'OK'},'DefaultOption',1)
                        
                    
                    end
                    
                case 'Kruskal-Wallis'
                    
                    
                case 'Mann-Whitney U'
                    
                    
            end
            
            
            [p,tbl,stats] = anova1(hogg);


            %}
            
            
            switch app.TestsButtonGroup.SelectedObject.Text
         
                case 'One-Way ANOVA'
                    html=ttest_html(app.selected_data,app.selected_group,...
                        app.data(:,app.predictors).Properties.VariableNames,app.TestsButtonGroup.SelectedObject.Text);
                    app.HTML.HTMLSource=html{1};
                    
                case 't-test'
                    html=ttest_html(app.selected_data,app.selected_group,...
                        app.data(:,app.predictors).Properties.VariableNames,app.TestsButtonGroup.SelectedObject.Text);
                    app.HTML.HTMLSource=html{1};
                    
                case 'Two-sample t-test'
                    html=ttest_html(app.selected_data,app.selected_group,...
                        app.data(:,app.predictors).Properties.VariableNames,app.TestsButtonGroup.SelectedObject.Text);
                    app.HTML.HTMLSource=html{1};
                    
                case 'Paired t-test'
                    html=ttest_html(app.selected_data,app.selected_group,...
                        app.data(:,app.predictors).Properties.VariableNames,app.TestsButtonGroup.SelectedObject.Text);
                    app.HTML.HTMLSource=html{1};
                    
            end
        end

        % Button pushed function: AssignFeaturesButton
        function AssignFeaturesButtonPushed(app, event)
            app.predictors=find(ismember(app.variables.Items, app.variables.Value)==1)
            app.respons=find(ismember(app.responses.Items, app.responses.Value)==1)
            
            
            app.selected_data=table2array(app.data(:,app.predictors))
            app.selected_group=table2array(app.data(:,app.respons))
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HTML = uihtml(app.UIFigure);
            app.PerformTest = uibutton(app.UIFigure, 'push');
            app.PerformTest.ButtonPushedFcn = createCallbackFcn(app, @PerformTestPushed, true);
            app.PerformTest.Text = 'Perform Test';
            app.FeaturesLabel = uilabel(app.UIFigure);
            app.FeaturesLabel.Text = 'Features';
            app.variables = uilistbox(app.UIFigure);
            app.variables.Items = {};
            app.variables.Multiselect = 'on';
            app.variables.Tooltip = {'Select the variables for the statistical test.'};
            app.variables.Value = {};
            app.GroupLabel = uilabel(app.UIFigure);
            app.GroupLabel.Text = 'Group';
            app.responses = uilistbox(app.UIFigure);
            app.responses.Items = {};
            app.responses.Multiselect = 'on';
            app.responses.Tooltip = {'Select the responses for the statistical test.'};
            app.responses.Value = {};
            app.TestsButtonGroup = uibuttongroup(app.UIFigure);
            app.TestsButtonGroup.Title = 'Tests';
            app.OneWayANOVAButton = uiradiobutton(app.TestsButtonGroup);
            app.OneWayANOVAButton.Enable = 'off';
            app.OneWayANOVAButton.Text = 'One-Way ANOVA';
            app.OneWayANOVAButton.Value = true;
            app.TwoWayANOVAButton = uiradiobutton(app.TestsButtonGroup);
            app.TwoWayANOVAButton.Enable = 'off';
            app.TwoWayANOVAButton.Text = 'Two-Way ANOVA';
            app.NWayANOVAButton = uiradiobutton(app.TestsButtonGroup);
            app.NWayANOVAButton.Enable = 'off';
            app.NWayANOVAButton.Text = 'N-Way ANOVA';
            app.MannWhitneyUButton = uiradiobutton(app.TestsButtonGroup);
            app.MannWhitneyUButton.Enable = 'off';
            app.MannWhitneyUButton.Text = 'Mann-Whitney U';
            app.KruskalWallisButton = uiradiobutton(app.TestsButtonGroup);
            app.KruskalWallisButton.Enable = 'off';
            app.KruskalWallisButton.Text = 'Kruskal-Wallis';
            app.ttestButton = uiradiobutton(app.TestsButtonGroup);
            app.ttestButton.Text = 't-test';
            app.RanksumButton = uiradiobutton(app.TestsButtonGroup);
            app.RanksumButton.Enable = 'off';
            app.RanksumButton.Text = 'Ranksum';
            app.PairedttestButton = uiradiobutton(app.TestsButtonGroup);
            app.PairedttestButton.Text = 'Paired t-test';
            app.TwosamplettestButton = uiradiobutton(app.TestsButtonGroup);
            app.TwosamplettestButton.Text = 'Two-sample t-test';
            app.AssignFeaturesButton = uibutton(app.UIFigure, 'push');
            app.AssignFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @AssignFeaturesButtonPushed, true);
            app.AssignFeaturesButton.Text = 'Assign Features';
            app.GIPHY = uihtml(app.UIFigure);
            app.GIPHY.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = statistical(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end